classdef GUI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure            matlab.ui.Figure
        EditField_2         matlab.ui.control.EditField
        UITable             matlab.ui.control.Table
        DistanceLabel       matlab.ui.control.Label
        EditField           matlab.ui.control.EditField
        BrowsefolderButton  matlab.ui.control.Button
        CancelButton        matlab.ui.control.Button
        DrawmodelButton     matlab.ui.control.Button
        UIAxes              matlab.ui.control.UIAxes
    end

    
    properties (Access = public)
        SurfacePlot matlab.graphics.primitive.Surface

        image_data_array
        cameras_txt
        images_txt

        origin
        side_lengths
        floor_walls

        wait_for_GUI = false
        my_wait_bar
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
           app.UIAxes.Visible = 'off';
           app.UITable.Visible = 'off';
           app.DrawmodelButton.Visible = 'off';
           app.EditField.Value = "Upload folder to create 3D model of your room";
           app.EditField_2.Visible = "off";
        end

        % Button pushed function: DrawmodelButton
        function DrawmodelButtonPushed(app, event)

            % Sample 3D coordinates
            app.DrawmodelButton.Visible = 'off';
            app.EditField.Visible = 'off';
            app.UIAxes.Visible = 'on';
            app.UITable.Visible = 'on';
            app.EditField_2.Visible = "on";
            app.EditField_2.Value = "Click on two points to measure the distance between them";


            num_objects = size(app.side_lengths,1);

            faces = [
                1 2 3 4;
                5 6 7 8; 
                1 2 6 5;
                2 3 7 6; 
                3 4 8 7; 
                4 1 5 8 
            ];


            for i=1:num_objects
                 % Extract coordinates of the origin
                    x = app.origin(i,1);
                    y = app.origin(i,2);
                    z = app.origin(i,3);
                    
                    % Extract side lengths
                    side_length_x = app.side_lengths(i,1);
                    side_length_y = app.side_lengths(i,2);
                    side_length_z = app.side_lengths(i,3);
                    
                    % Define the vertices of the box
                    vertices = [
                        x - side_length_x/2, y - side_length_y/2, z - side_length_z/2;
                        x + side_length_x/2, y - side_length_y/2, z - side_length_z/2;
                        x + side_length_x/2, y + side_length_y/2, z - side_length_z/2;
                        x - side_length_x/2, y + side_length_y/2, z - side_length_z/2;
                        x - side_length_x/2, y - side_length_y/2, z + side_length_z/2;
                        x + side_length_x/2, y - side_length_y/2, z + side_length_z/2;
                        x + side_length_x/2, y + side_length_y/2, z + side_length_z/2;
                        x - side_length_x/2, y + side_length_y/2, z + side_length_z/2
                    ];
                

                patch('Parent', app.UIAxes, 'Vertices', vertices, 'Faces', faces, 'FaceColor', 'blue', 'FaceAlpha', 0.5,'ButtonDownFcn',@mouse_click);
            end
            

            %Plot the floor and walls
            for i=1:size(app.floor_walls,2)
                M = app.floor_walls{1,i};
                if i == 1
                    patch('Parent', app.UIAxes,'XData', M(1,:), 'YData', M(2,:), 'ZData', M(3,:), 'faceColor', 'black', 'FaceAlpha', 0.2);
                else
                    patch('Parent', app.UIAxes,'XData', M(1,:), 'YData', M(2,:), 'ZData', M(3,:)/5, 'faceColor', 'yellow', 'FaceAlpha', 0.3,'ButtonDownFcn',@mouse_click);
                end
            
            end


            function mouse_click(~,event)
                % Get the clicked points' coordinates
                coords = event.IntersectionPoint;
                numpoints = size(app.UITable.Data,1);
                if numpoints ~=1 %The first point
                    %delete previous points
                    app.UITable.Data = [];
                    %add new point
                    app.UITable.Data(1,:) = [coords(1),coords(2), coords(3)]; 
                else %The second point

                    %Save datapoint
                    app.UITable.Data(2,:) = [coords(1),coords(2), coords(3)]; 
                    x_data = [app.UITable.Data(1,1) app.UITable.Data(2,1)];
                    y_data =  [app.UITable.Data(1,2) app.UITable.Data(2,2) ];
                    z_data =  [app.UITable.Data(1,3) app.UITable.Data(2,3) ];

                    % Find and delete existing line objects
                    delete(findobj(app.UIAxes, 'Type', 'line'));

                    hold(app.UIAxes, 'on'); 
                    plot3(app.UIAxes, x_data ,y_data,z_data, 'Color', 'red');
                    hold(app.UIAxes, 'off');

                    distance = sqrt((diff(x_data))^2 + (diff(y_data))^2 + (diff(z_data))^2);

                    app.DistanceLabel.Text = sprintf('Distance [m] = %.2f', distance);
                end
            end
            
        end

        % Button pushed function: BrowsefolderButton
        function BrowsefolderButtonPushed(app, event)
            % Get the path of the MATLAB file
            matlab_file_path = mfilename('fullpath');
            [folder_path, ~, ~] = fileparts(matlab_file_path);
            
            % Display a dialog box to choose a folder
            folder_path = uigetdir(folder_path, 'Select a folder');
            if isequal(folder_path, 0)
                disp('No folder selected.');
                return;
            end
            
            % Read image files from the "images" folder
            image_folder_path = fullfile(folder_path, 'images');
            image_file_names = dir(fullfile(image_folder_path, '*.jpg'));
            num_images = numel(image_file_names);

            % Read and save the .txt files from the "parameters" folder
            parameters_folder_path = fullfile(folder_path, 'parameters');
            txt_file_names = dir(fullfile(parameters_folder_path, '*.txt'));
            
            % Initialize a cell array to store the image data
            app.image_data_array = cell(num_images, 1);
            
            % Read and store the image data for each selected image
            h = waitbar(0,'Please wait...');
            for i = 1:num_images
                image_path = fullfile(image_folder_path, image_file_names(i).name);
                app.image_data_array{i} = image_path;
                waitbar(i / (num_images + numel(txt_file_names)));
                new_text = sprintf('%d / %d Images uploaded', i, num_images);
                app.EditField.Value = new_text;
            end
           
            % Get the current text in the edit field
            current_text = app.EditField.Value;
            

            for j = 1:numel(txt_file_names)
                txt_file_path = fullfile(parameters_folder_path, txt_file_names(j).name);
                [~, txt_file_name, ~] = fileparts(txt_file_path);
                disp(txt_file_name);
                if txt_file_name == "cameras"
                    app.cameras_txt = readmatrix(txt_file_path);
                elseif txt_file_name == "images"
                    app.images_txt = readmatrix(txt_file_path);
                end
                    

                new_text = sprintf('%d / %d Textfiles uploaded', j, numel(txt_file_names));
                waitbar((i+j )/ (num_images + numel(txt_file_names)));
                % Append the new text below the old text with a newline character
                updated_text = sprintf('%s \n\n %s', current_text, new_text);
                app.EditField.Value = updated_text;
            end
            close(h);
            
            app.wait_for_GUI = 1;
            app.EditField.Value = "Please wait while the 3D model is building";
            app.BrowsefolderButton.Visible = "off";
            
        end

        % Button pushed function: CancelButton
        function CancelButtonPushed(app, event)
            close(app.UIFigure);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, '3D-map')
            xlabel(app.UIAxes, ' [m]')
            ylabel(app.UIAxes, ' [m]')
            zlabel(app.UIAxes, 'Height [m]')
            app.DrawmodelButton = uibutton(app.UIFigure, 'push');
            app.DrawmodelButton.ButtonPushedFcn = createCallbackFcn(app, @DrawmodelButtonPushed, true);
            app.DrawmodelButton.Text = 'Draw model';
            app.CancelButton = uibutton(app.UIFigure, 'push');
            app.CancelButton.ButtonPushedFcn = createCallbackFcn(app, @CancelButtonPushed, true);
            app.CancelButton.Text = 'Cancel';
            app.BrowsefolderButton = uibutton(app.UIFigure, 'push');
            app.BrowsefolderButton.ButtonPushedFcn = createCallbackFcn(app, @BrowsefolderButtonPushed, true);
            app.BrowsefolderButton.Text = 'Browse folder';
            app.EditField = uieditfield(app.UIFigure, 'text');
            app.DistanceLabel = uilabel(app.UIFigure);
            app.DistanceLabel.Text = '';
            app.UITable = uitable(app.UIFigure);
            app.UITable.ColumnName = {'X [m]'; 'Y [m]'; 'Z [m]'};
            app.UITable.RowName = {};
            app.EditField_2 = uieditfield(app.UIFigure, 'text');
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = GUI

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end