classdef GUI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure            matlab.ui.Figure
        BrowseimagesButton  matlab.ui.control.Button
        DrawmodelButton     matlab.ui.control.Button
        CancelButton        matlab.ui.control.Button
        BrowsefolderButton  matlab.ui.control.Button
        EditField           matlab.ui.control.EditField
        DistanceLabel       matlab.ui.control.Label
        UITable             matlab.ui.control.Table
        UIAxes              matlab.ui.control.UIAxes
    end

    
    properties (Access = public)
        SurfacePlot matlab.graphics.primitive.Surface
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
           app.UIAxes.Visible = 'off';
           app.UITable.Visible = 'off';
           app.DrawmodelButton.Visible = 'off';
        end

        % Button pushed function: BrowseimagesButton
        function BrowseimagesButtonPushed(app, event)
            [fileNames, path] = uigetfile({'*.jpg;*.jpeg;*.png;*.bmp;*.txt'}, 'Select image files', 'MultiSelect', 'on');
            if isequal(fileNames, 0)
                disp('No image files selected.')
                return;
            end
            if iscell(fileNames)
                numFiles= numel(fileNames);
            else
                numFiles = 1;
            end
                       
            imageDataArray = cell(1, numFiles);
            textDataArray = cell(1, numFiles);
            h = waitbar(0,'Please wait...');
            for i = 1:numFiles
                filePath = fullfile(path, fileNames{i});
                % Check file extension
                [~, ~, fileExt] = fileparts(filePath);
                if strcmpi(fileExt, '.txt')
                    % Read and store text data
                    fileData = fileread(filePath);
                    textDataArray{i} = fileData;
                else
                    % Read and store image data
                    imageDataArray{i} = imread(filePath);
                end
                waitbar(i / numFiles);
                newText = sprintf('%d / %d Files uploaded', i, numFiles);
                app.EditField.Value = newText;
            end
            save('imageData.mat', 'imageDataArray', '-v7.3');
            if ~isempty(textDataArray)
                save('textData.mat', 'textDataArray', '-v7.3');
            end
            close(h);
            app.DrawmodelButton.Visible = 'on';
        end

        % Button pushed function: DrawmodelButton
        function DrawmodelButtonPushed(app, event)
            %run('get_global_coordinates_list.m');
            %close(app.UIFigure);
            
            % Sample 3D coordinates
            app.BrowsefolderButton.Visible = 'off';
            app.BrowseimagesButton.Visible = 'off';
            app.DrawmodelButton.Visible = 'off';
            app.EditField.Visible = 'off';
            app.UIAxes.Visible = 'on';
            app.UITable.Visible = 'on';
            
            %%INSERT MODEL HERE
            h = waitbar(0,'Please wait...');
            
            for i = 1:5
                pause(1);
                waitbar(i / 5);
                %upload model here
                %tar inn origin og SideLength
            end
            close(h);
            [origin, sideLengths, X_floor, Y_floor, Z_floor] = get_boxes();
            numObjects = size(sideLengths,1);
            vertices = zeros(8,3,numObjects);
            
            faces = [
                1 2 3 4;  % Bottom face
                5 6 7 8;  % Top face
                1 2 6 5;  % Side face 1
                2 3 7 6;  % Side face 2
                3 4 8 7;  % Side face 3
                4 1 5 8   % Side face 4
            ];
            
            
            for i=1:numObjects
                % Extract coordinates of the origin
                x = origin(i,1);
                y = origin(i,2);
                z = origin(i,3);
                
                % Extract side lengths
                sideLengthX = sideLengths(i,1);
                sideLengthY = sideLengths(i,2);
                sideLengthZ = sideLengths(i,3);
                
                % Define the vertices of the box
                vertices(:,:,i) = [
                    x, y, z;                        % Vertex 1
                    x + sideLengthX, y, z;          % Vertex 2
                    x + sideLengthX, y + sideLengthY, z;  % Vertex 3
                    x, y + sideLengthY, z;          % Vertex 4
                    x, y, z + sideLengthZ;          % Vertex 5
                    x + sideLengthX, y, z + sideLengthZ;  % Vertex 6
                    x + sideLengthX, y + sideLengthY, z + sideLengthZ;  % Vertex 7
                    x, y + sideLengthY, z + sideLengthZ   % Vertex 8
                ];
                patch('Parent', app.UIAxes, 'Vertices', vertices(:,:,i), 'Faces', faces, 'FaceColor', 'blue', 'FaceAlpha', 0.5,'ButtonDownFcn',@mouse_click);
                
            end
            %Plot the floor
            patch('Parent', app.UIAxes,'XData', X_floor, 'YData', Y_floor, 'ZData', Z_floor);


            % TEST VERTICES : TO BE DELETED
            %vertices = zeros(8,3,2);
            %box1
            %vertices(:,:,1) = [
            %    0 0 0;  % Vertex 1
            %    1 0 0;  % Vertex 2
            %    1 1 0;  % Vertex 3
            %    0 1 0;  % Vertex 4
            %    0 0 1;  % Vertex 5
            %    1 0 1;  % Vertex 6
            %    1 1 1;  % Vertex 7
            %    0 1 1   % Vertex 8
            %];
            %box2
            %vertices(:,:,2) =[
            %                    4 4 4;  % Vertex 1
            %    5 4 4;  % Vertex 2
            %    5 5 4;  % Vertex 3
            %    4 5 4;  % Vertex 4
            %    4 4 5;  % Vertex 5
            %    5 4 5;  % Vertex 6
            %    5 5 5;  % Vertex 7
            %    4 5 5   % Vertex 8
            %    ];
            % Define the faces of the box
            

            %for i=1:size(vertices,3)
            %    patch('Parent', app.UIAxes, 'Vertices', vertices(:,:,i), 'Faces', faces, 'FaceColor', 'blue', 'FaceAlpha', 0.5,'ButtonDownFcn',@mouse_click);
            %end
            

            function mouse_click(~,event)
                % Get the clicked points' coordinates
                coords = event.IntersectionPoint;
                % do something with the coordinates (e.g. add coordinates to table)
                numpoints = size(app.UITable.Data,1);
                if numpoints ~=1 %The first point
                    %delete previos points
                    app.UITable.Data = [];
                    %add new point
                    app.UITable.Data(1,:) = [coords(1),coords(2), coords(3)]; 
                else %The second point
                    
                    %Save datapoint
                    app.UITable.Data(2,:) = [coords(1),coords(2), coords(3)]; 
                    xData = [app.UITable.Data(1,1) app.UITable.Data(2,1)];
                    yData =  [app.UITable.Data(1,2) app.UITable.Data(2,2) ];
                    zData =  [app.UITable.Data(1,3) app.UITable.Data(2,3) ];
                    
                    % Find and delete existing line objects
                    delete(findobj(app.UIAxes, 'Type', 'line'));
                    
                    %Plot new line
                    hold(app.UIAxes, 'on'); 
                    plot3(app.UIAxes, xData ,yData,zData, 'Color', 'red');
                    hold(app.UIAxes, 'off');
                    
                    % Calculate the distance between the two clicked points
                    distance = sqrt((diff(xData))^2 + (diff(yData))^2 + (diff(zData))^2);
            
                    % Display the distance in a text label
                    app.DistanceLabel.Text = sprintf('Distance [m] = %.2f', distance);
                end
            end
            
        end

        % Button pushed function: BrowsefolderButton
        function BrowsefolderButtonPushed(app, event)
            % Get the path of the MATLAB file
            matlabFilePath = mfilename('fullpath');
            [folderPath, ~, ~] = fileparts(matlabFilePath);
            
            % Display a dialog box to choose a folder
            folderPath = uigetdir(folderPath, 'Select a folder');
            if isequal(folderPath, 0)
                disp('No folder selected.');
                return;
            end
            
            % Read image files from the "images" folder
            imageFolderPath = fullfile(folderPath, 'images');
            imageFileNames = dir(fullfile(imageFolderPath, '*.jpg'));
            numImages = numel(imageFileNames);

            % Read and save the .txt files from the "parameters" folder
            parametersFolderPath = fullfile(folderPath, 'parameters');
            txtFileNames = dir(fullfile(parametersFolderPath, '*.txt'));
            
            % Initialize a cell array to store the image data
            imageDataArray = cell(1, numImages);
            
            % Read and store the image data for each selected image
            h = waitbar(0,'Please wait...');
            for i = 1:numImages
                imagePath = fullfile(imageFolderPath, imageFileNames(i).name);
                imageDataArray{i} = imread(imagePath);
                waitbar(i / (numImages + numel(txtFileNames)));
                newText = sprintf('%d / %d Images uploaded', i, numImages);
                app.EditField.Value = newText;
            end
           
            % Get the current text in the edit field
            currentText = app.EditField.Value;
            
            % Save image data as 'imageData.mat' in the chosen folder
            save('imageData.mat', 'imageDataArray', '-v7.3');


            for j = 1:numel(txtFileNames)
                txtFilePath = fullfile(parametersFolderPath, txtFileNames(j).name);
                % txtData = fileread(txtFilePath);
                
                [~, txtFileName, ~] = fileparts(txtFilePath);
                
                % fid = fopen([txtFileName '.txt'], 'w');
                % fprintf(fid, '%s', txtData);
                % fclose(fid);
                copyfile(txtFilePath, [txtFileName '.txt'], 'f');
                            % Modify the text
                newText = sprintf('%d / %d Textfiles uploaded', j, numel(txtFileNames));
                waitbar((i+j )/ (numImages + numel(txtFileNames)));
                % Append the new text below the old text with a newline character
                updatedText = sprintf('%s \n\n %s', currentText, newText);
                app.EditField.Value = updatedText;
            end
            close(h);
            app.DrawmodelButton.Visible = 'on';
        end

        % Button pushed function: CancelButton
        function CancelButtonPushed(app, event)
            close(app.UIFigure);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.BrowseimagesButton = uibutton(app.UIFigure, 'push');
            app.BrowseimagesButton.ButtonPushedFcn = createCallbackFcn(app, @BrowseimagesButtonPushed, true);
            app.BrowseimagesButton.Text = 'Browse images';
            app.DrawmodelButton = uibutton(app.UIFigure, 'push');
            app.DrawmodelButton.ButtonPushedFcn = createCallbackFcn(app, @DrawmodelButtonPushed, true);
            app.DrawmodelButton.Text = 'Draw model';
            app.CancelButton = uibutton(app.UIFigure, 'push');
            app.CancelButton.ButtonPushedFcn = createCallbackFcn(app, @CancelButtonPushed, true);
            app.CancelButton.Text = 'Cancel';
            app.BrowsefolderButton = uibutton(app.UIFigure, 'push');
            app.BrowsefolderButton.ButtonPushedFcn = createCallbackFcn(app, @BrowsefolderButtonPushed, true);
            app.BrowsefolderButton.Text = 'Browse folder';
            app.EditField = uieditfield(app.UIFigure, 'text');
            app.DistanceLabel = uilabel(app.UIFigure);
            app.DistanceLabel.Text = '';
            app.UITable = uitable(app.UIFigure);
            app.UITable.ColumnName = {'X [m]'; 'Y [m]'; 'Z [m]'};
            app.UITable.RowName = {};
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, '3D-map')
            xlabel(app.UIAxes, 'X [m]')
            ylabel(app.UIAxes, 'Y [m]')
            zlabel(app.UIAxes, 'Z [m]')
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = GUI

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end